classdef BallCollision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        SpeedLabel          matlab.ui.control.Label
        SpeedSlider         matlab.ui.control.Slider
        ActionButtonsPanel  matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        QuitButton          matlab.ui.control.Button
        DeleteBallButton    matlab.ui.control.Button
        AddBallButton       matlab.ui.control.Button
        ResetButton         matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        balls
        axesLimits = [0 70 0 70];
        timerObj
        dt = 0.05
        isRunning = false;
    end
    
    methods (Access = private)
        function updateSimulation(app)
            if ~app.isRunning
                return;
            end

            % Przesuwanie piłek
            for i = 1:length(app.balls)
                app.balls(i) = app.balls(i).NewLocation(app.balls(i).x + app.balls(i).vx*app.dt, ...
                                                app.balls(i).y + app.balls(i).vy*app.dt);

                for k = i+1:length(app.balls)
                    if (app.balls(i).Distance(app.balls(k)) < app.balls(i).r + app.balls(k).r + 1e-3)
                        [app.balls(i), app.balls(k)] = Collision(app.balls(i), app.balls(k));
                    end
                end
                app.balls(i) = app.balls(i).BorderCollision(app.axesLimits);
            end

            % Czyszczenie i rysowanie
            cla(app.UIAxes);
            axis(app.UIAxes, app.axesLimits);
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, 'off');
            hold(app.UIAxes, 'on');
            for i = 1:length(app.balls)
                app.balls(i).Show(app.UIAxes);
            end
            plot(app.UIAxes, [app.axesLimits(1), app.axesLimits(2), app.axesLimits(2), app.axesLimits(1), app.axesLimits(1)], ...
                       [app.axesLimits(3), app.axesLimits(3), app.axesLimits(4), app.axesLimits(4), app.axesLimits(3)], ...
                       'k-', 'LineWidth', 3);
            drawnow;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            b1 = ball(10, 10, 9, 5, 1, 5);
            b2 = ball(40, 10, -10, -6, 4, 3);
            b3 = ball(10, 40, 6, 7, 1, 7);
            b4 = ball(40, 40, 30, -5, 1, 4);
            b5 = ball(20, 20, 7, -10, 1, 6);
            b6 = ball(60, 30, 3, -8, 1, 8);
            
            app.balls = [b1, b2, b3, b4, b5, b6];

            
            axis(app.UIAxes, app.axesLimits);
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, 'off');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [app.axesLimits(1), app.axesLimits(2), app.axesLimits(2), app.axesLimits(1), app.axesLimits(1)], ...
                   [app.axesLimits(3), app.axesLimits(3), app.axesLimits(4), app.axesLimits(4), app.axesLimits(3)], ...
                   'k-', 'LineWidth', 3);

            if isempty(app.timerObj) || ~isvalid(app.timerObj)
                app.timerObj = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', app.dt, ...
                    'TimerFcn', @(~,~) app.updateSimulation());
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.isRunning
                stop(app.timerObj);
                app.isRunning = false;
                app.StartButton.Text = 'Start';
            else
                start(app.timerObj);
                app.isRunning = true;
                app.StartButton.Text = 'Pause';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            stop(app.timerObj);
            app.isRunning = false;
            app.StartButton.Text = 'Start';
            delete(app.timerObj);
            cla(app.UIAxes);
            startupFcn(app);
            app.SpeedSlider.Value = 1;
            
            updateSimulation(app);
        end

        % Button pushed function: AddBallButton
        function AddBallButtonPushed(app, event)
            prompt = {'Podaj x:', 'Podaj y:', 'Podaj v_x:', 'Podaj v_y:', 'Podaj m:', 'Podaj r:'};
            dlgtitle = 'Dodaj piłkę';
            dims = [1 35];
            definput = {'35','35','10','10','1','5'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            if ~isempty(answer)
                newBall = ball(str2double(answer{1}), str2double(answer{2}), str2double(answer{3}), ...
                    str2double(answer{4}), str2double(answer{5}), str2double(answer{6}));
                app.balls(end+1) = newBall;
            end
        end

        % Button pushed function: DeleteBallButton
        function DeleteBallButtonPushed(app, event)
            if ~isempty(app.balls)
                app.balls(end) = [];
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            if isvalid(app.timerObj)
                stop(app.timerObj);
                delete(app.timerObj);
            end
            delete(app);
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            app.dt = 0.02 * app.SpeedSlider.Value;
            if app.isRunning
                stop(app.timerObj);
                app.timerObj.Period = app.dt;
                start(app.timerObj);
            else
                app.timerObj.Period = app.dt;
            end

            % Druga wersja do zmiany prędkości symulacji - porzucona
            % m = app.SpeedSlider.Value;
            % if app.isRunning
            %     stop(app.timerObj);
            %     for i = 1:length(app.balls)
            %         app.balls(i).vx = app.balls(i).vx * m;
            %         app.balls(i).vy = app.balls(i).vy * m;
            %     end
            %     app.timerObj.Period = 0.01 / app.SpeedSlider.Value;
            %     start(app.timerObj);
            % else
            %     for i = 1:length(app.balls)
            %         app.balls(i).vx = app.balls(i).vx * m;
            %         app.balls(i).vy = app.balls(i).vy * m;
            %     end
            %     app.timerObj.Period = 0.01 / app.SpeedSlider.Value;
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Ball Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ActionButtonsPanel = uipanel(app.GridLayout);
            app.ActionButtonsPanel.Title = 'Action Buttons';
            app.GridLayout2 = uigridlayout(app.ActionButtonsPanel);
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AddBallButton = uibutton(app.GridLayout2, 'push');
            app.AddBallButton.ButtonPushedFcn = createCallbackFcn(app, @AddBallButtonPushed, true);
            app.AddBallButton.Text = 'Add Ball';
            app.DeleteBallButton = uibutton(app.GridLayout2, 'push');
            app.DeleteBallButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBallButtonPushed, true);
            app.DeleteBallButton.Text = 'Delete Ball';
            app.QuitButton = uibutton(app.GridLayout2, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.SpeedSlider = uislider(app.GridLayout);
            app.SpeedSlider.Limits = [1 4];
            app.SpeedSlider.MajorTicks = [1 2 3 4];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Value = 1;
            app.SpeedLabel = uilabel(app.GridLayout);
            app.SpeedLabel.Text = 'Speed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BallCollision

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end